classdef winAPTmain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        MessageBox               matlab.ui.control.TextArea
        Button                   matlab.ui.control.Button
        ControlsPanel            matlab.ui.container.Panel
        MethodDropDown           matlab.ui.control.DropDown
        MethodDropDownLabel      matlab.ui.control.Label
        TotalsDropDown           matlab.ui.control.DropDown
        TotalsDropDownLabel      matlab.ui.control.Label
        PreAmpLamp               matlab.ui.control.Lamp
        PreAmpDropDown           matlab.ui.control.DropDown
        PreAmpDropDownLabel      matlab.ui.control.Label
        IntervalsEditField       matlab.ui.control.NumericEditField
        IntervalsEditFieldLabel  matlab.ui.control.Label
        SamplesEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel    matlab.ui.control.Label
        AttSpinner               matlab.ui.control.Spinner
        AttSpinnerLabel          matlab.ui.control.Label
        SmoothEditField          matlab.ui.control.NumericEditField
        SmoothEditFieldLabel     matlab.ui.control.Label
        PointsEditField          matlab.ui.control.NumericEditField
        PointsEditFieldLabel     matlab.ui.control.Label
        MeansParmEditField       matlab.ui.control.NumericEditField
        MeansParmEditFieldLabel  matlab.ui.control.Label
        VBWEditField             matlab.ui.control.NumericEditField
        VBWEditFieldLabel        matlab.ui.control.Label
        RBWSpinner               matlab.ui.control.Spinner
        RBWSpinnerLabel          matlab.ui.control.Label
        SpanEditField            matlab.ui.control.NumericEditField
        SpanEditFieldLabel       matlab.ui.control.Label
        CWEditField              matlab.ui.control.NumericEditField
        CWEditFieldLabel         matlab.ui.control.Label
        PRESETButton             matlab.ui.control.Button
        SaturationTestButton     matlab.ui.control.Button
        AutoScaleButton          matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        ExecutionPanel           matlab.ui.container.Panel
        RunButton                matlab.ui.control.Button
        RunMode                  matlab.ui.control.Switch
        Button_MaxHold           matlab.ui.control.StateButton
        Button_Average           matlab.ui.control.StateButton
        Button_MinHold           matlab.ui.control.StateButton
        Plot_Panel               matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        GPSPanel                 matlab.ui.container.Panel
        GPS_Connectivity         matlab.ui.control.Button
        GPS_FixedStation         matlab.ui.control.Button
        GPS_List                 matlab.ui.control.DropDown
        Image2                   matlab.ui.control.Image
        AntennaPanel             matlab.ui.container.Panel
        AntennaName              matlab.ui.control.DropDown
        Img_antena               matlab.ui.control.Image
        ReceiverPanel            matlab.ui.container.Panel
        Receiver_EditList        matlab.ui.control.Button
        Receiver_Connectivity    matlab.ui.control.Button
        Receiver_List            matlab.ui.control.DropDown
        Image                    matlab.ui.control.Image
        Img_receiver             matlab.ui.control.Image
        RightPanel               matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        MeasuresButtonGroup      matlab.ui.container.ButtonGroup
        HarmonicsButton          matlab.ui.control.RadioButton
        ChannelPowerButton       matlab.ui.control.RadioButton
        OCWxdBButton             matlab.ui.control.RadioButton
        OCWBetaButton            matlab.ui.control.RadioButton
        NoneButton               matlab.ui.control.RadioButton
        Results                  matlab.ui.control.HTML
        Panel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CallingApp
        
        receiverObj
        EB500Obj
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_ModeOperation(app)
            % app.receiverObj
            idx2 = find(app.receiverObj.List.Enable)';

            app.Receiver_List.Items = {};
            for ii = idx2
                Parameters = jsondecode(app.receiverObj.List.Parameters{ii});

                if isfield(Parameters, 'IP') && isfield(Parameters, 'Port')
                    Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                elseif isfield(Parameters, 'IP')
                    Socket = Parameters.IP;
                else
                    Socket = Parameters.Port;
                end

                app.Receiver_List.Items(end+1) = {sprintf('ID %d: %s - %s', ii, app.receiverObj.List.Name{ii}, Socket)};
            end
        end


        %-----------------------------------------------------------------%
        function [instrHandle, msgError] = ConnectivityTest_Receiver_Aux(app, MessageBoxFlag)

            instrumentName = extractBetween(app.Receiver_List.Value, ': ', ' -');

            idx1 = str2double(char(extractBetween(app.Receiver_List.Value, 'ID', ':')));
            idx2 = find(strcmp(app.receiverObj.Config.Name, instrumentName), 1);

            instrSelected = struct('Type',       app.receiverObj.List.Type{idx1},  ...
                                   'Tag',        app.receiverObj.Config.Tag{idx2}, ...
                                   'Parameters', jsondecode(app.receiverObj.List.Parameters{idx1}));

            [instrHandle, msgError] = fcn.ConnectivityTest_Receiver(app, instrSelected, MessageBoxFlag);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp  = mainApp;
            app.receiverObj = app.CallingApp.receiverObj;
            app.EB500Obj    = app.CallingApp.EB500Obj;

            startup_ModeOperation(app)

            % app.InstrumentbenchPanel.Title = strcat(['Instrument Bench - V ', apt.Analysers.CONSTANTS.VERSION]);
            
        end

        % Button pushed function: AutoScaleButton, LoadButton, RunButton
        function ToolButtonPushed(app, event)
            switch event.Source
                case app.RunButton
                    if app.RunButton.Text == "RUN"

                        set(app.RunButton, Enable = 'off')     
                        
                        pause(2); % Precessing here

                        set(app.RunButton, Enable = 'on', ...
                                           Text = "STOP", ...
                                           Icon = "LT_stop.png", ...
                                           BackgroundColor = 'white', ...
                                           FontColor = 'black');
                            app.IntervalsEditField.Enable = "off";
                    else
                        app.RunButton.Text = "RUN";
                        app.RunButton.Icon = "LT_play.png";
                        app.RunButton.BackgroundColor = 'white';
                        % app.RunButton.BackgroundColor = [0.39,0.83,0.07];
                        % app.RunButton.FontColor = 'black';
                        app.IntervalsEditField.Enable = "on";
                    end

                case app.LoadButton
                    % app.TextArea_results.Value = "Um número qualquer: " + string(randi(10)) + " E isso pelo load...";
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin7 = [];
            delete(app)
            
        end

        % Button pushed function: Receiver_EditList
        function Receiver_EditListButtonPushed(app, event)
            
            if isempty(app.CallingApp.auxiliarWin4)
                d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog', '');

                app.CallingApp.auxiliarWin4 = auxApp.winEditInstrumentList(app.CallingApp);
                drawnow nocallbacks

                delete(d)
            else
                figure(app.CallingApp.auxiliarWin4.UIFigure)
            end

        end

        % Button pushed function: Receiver_Connectivity
        function Receiver_ConnectivityButtonPushed(app, event)
            
            d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog', '');
            ConnectivityTest_Receiver_Aux(app, 1);            
            delete(d)

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            startup_ModeOperation(app)

        end

        % Value changed function: PreAmpDropDown
        function PreAmpDropDownValueChanged(app, event)
            value = app.PreAmpDropDown.Value;
            if value == "On"
                app.PreAmpLamp.Color = [0,1,0];
            else
                app.PreAmpLamp.Color = [0.65,0.65,0.65];
            end
        end

        % Value changed function: Receiver_List
        function Receiver_ListValueChanged(app, event)
            value = app.Receiver_List.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {592, 592};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {832, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReceiverPanel = uipanel(app.LeftPanel);
            app.ReceiverPanel.Title = '      Receiver';
            app.Img_receiver = uiimage(app.ReceiverPanel);
            app.Img_receiver.ImageSource = 'LT_playback.png';
            app.Image = uiimage(app.ReceiverPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'LT_refresh.png';
            app.Receiver_List = uidropdown(app.ReceiverPanel);
            app.Receiver_List.Items = {};
            app.Receiver_List.ValueChangedFcn = createCallbackFcn(app, @Receiver_ListValueChanged, true);
            app.Receiver_List.Tag = 'task_Editable';
            app.Receiver_List.Value = {};
            app.Receiver_Connectivity = uibutton(app.ReceiverPanel, 'push');
            app.Receiver_Connectivity.ButtonPushedFcn = createCallbackFcn(app, @Receiver_ConnectivityButtonPushed, true);
            app.Receiver_Connectivity.Tag = 'task_Editable';
            app.Receiver_Connectivity.Icon = 'LT_connectivity.png';
            app.Receiver_Connectivity.Tooltip = {'Teste de conectividade'};
            app.Receiver_Connectivity.Text = '';
            app.Receiver_EditList = uibutton(app.ReceiverPanel, 'push');
            app.Receiver_EditList.ButtonPushedFcn = createCallbackFcn(app, @Receiver_EditListButtonPushed, true);
            app.Receiver_EditList.Tag = 'task_Editable';
            app.Receiver_EditList.Icon = 'LT_connect.png';
            app.Receiver_EditList.Tooltip = {'Editar lista de instrumentos'};
            app.Receiver_EditList.Text = '';
            app.AntennaPanel = uipanel(app.LeftPanel);
            app.AntennaPanel.Title = '      Antenna';
            app.Img_antena = uiimage(app.AntennaPanel);
            app.Img_antena.ImageSource = 'LT_antenna.png';
            app.AntennaName = uidropdown(app.AntennaPanel);
            app.AntennaName.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.AntennaName.Tag = 'task_Editable';
            app.AntennaName.Value = '';
            app.GPSPanel = uipanel(app.LeftPanel);
            app.GPSPanel.Title = '      GPS';
            app.Image2 = uiimage(app.GPSPanel);
            app.Image2.ImageSource = 'Instr_Global.png';
            app.GPS_List = uidropdown(app.GPSPanel);
            app.GPS_List.Items = {'ID 0: Manual'};
            app.GPS_List.Tag = 'task_Editable';
            app.GPS_List.Value = 'ID 0: Manual';
            app.GPS_FixedStation = uibutton(app.GPSPanel, 'push');
            app.GPS_FixedStation.Tag = 'task_Editable';
            app.GPS_FixedStation.Icon = 'LT_pin.png';
            app.GPS_FixedStation.Tooltip = {'Importa coordenadas geográficas da estação'};
            app.GPS_FixedStation.Text = '';
            app.GPS_Connectivity = uibutton(app.GPSPanel, 'push');
            app.GPS_Connectivity.Tag = 'task_Editable';
            app.GPS_Connectivity.Icon = 'LT_connectivity.png';
            app.GPS_Connectivity.Tooltip = {'Teste de conectividade'};
            app.GPS_Connectivity.Text = '';
            app.Plot_Panel = uipanel(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.Plot_Panel);
            app.Button_MinHold = uibutton(app.LeftPanel, 'state');
            app.Button_MinHold.Text = '';
            app.Button_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.Button_Average = uibutton(app.LeftPanel, 'state');
            app.Button_Average.Text = '';
            app.Button_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.Button_MaxHold = uibutton(app.LeftPanel, 'state');
            app.Button_MaxHold.Text = '';
            app.Button_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.ControlsPanel = uipanel(app.LeftPanel);
            app.ControlsPanel.Title = 'Controls';
            app.ExecutionPanel = uipanel(app.ControlsPanel);
            app.ExecutionPanel.Title = 'Execution';
            app.RunMode = uiswitch(app.ExecutionPanel, 'slider');
            app.RunMode.Items = {'Single', 'Continuous'};
            app.RunMode.Tooltip = {'Continuous mode redo samples in moving average.'};
            app.RunMode.Value = 'Continuous';
            app.RunButton = uibutton(app.ExecutionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.RunButton.Icon = 'LT_play.png';
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = 'RUN';
            app.SaveButton = uibutton(app.ControlsPanel, 'push');
            app.SaveButton.Icon = 'LT_save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {''};
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ControlsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.LoadButton.Icon = 'LT_OpenFile.png';
            app.LoadButton.Enable = 'off';
            app.LoadButton.Tooltip = {''};
            app.LoadButton.Text = 'Load';
            app.AutoScaleButton = uibutton(app.ControlsPanel, 'push');
            app.AutoScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.AutoScaleButton.Icon = 'LT_Detection.png';
            app.AutoScaleButton.Tooltip = {'Commands a instrument do do a rescale amplitude of signal'};
            app.AutoScaleButton.Text = 'Auto Scale';
            app.SaturationTestButton = uibutton(app.ControlsPanel, 'push');
            app.SaturationTestButton.Icon = 'LR_Persistance.png';
            app.SaturationTestButton.Tooltip = {'Test Att on marker in CW freq, May not exceed 1dB!'};
            app.SaturationTestButton.Text = 'Saturation Test';
            app.PRESETButton = uibutton(app.ControlsPanel, 'push');
            app.PRESETButton.Icon = 'LT_task.png';
            app.PRESETButton.Tooltip = {'Proceed a Instrument Preset to default.'};
            app.PRESETButton.Text = 'PRESET';
            app.CWEditFieldLabel = uilabel(app.ControlsPanel);
            app.CWEditFieldLabel.Text = 'CW';
            app.CWEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.CWEditField.Limits = [0 Inf];
            app.CWEditField.Tooltip = {'Central frequency (MHz)'};
            app.CWEditField.Value = 100.3;
            app.SpanEditFieldLabel = uilabel(app.ControlsPanel);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SpanEditField.Tooltip = {'Span (kHz)'};
            app.SpanEditField.Value = 500;
            app.RBWSpinnerLabel = uilabel(app.ControlsPanel);
            app.RBWSpinnerLabel.Text = 'RBW';
            app.RBWSpinner = uispinner(app.ControlsPanel);
            app.RBWSpinner.Step = 100;
            app.RBWSpinner.Limits = [0 Inf];
            app.RBWSpinner.Tooltip = {'Resolution Bandwidth (kHz)'};
            app.RBWSpinner.Value = 200;
            app.VBWEditFieldLabel = uilabel(app.ControlsPanel);
            app.VBWEditFieldLabel.Text = 'VBW';
            app.VBWEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.VBWEditField.Limits = [0 Inf];
            app.VBWEditField.Editable = 'off';
            app.VBWEditField.Tooltip = {'It may be a fixed value (kHz) or a ratio (ex. 1:5)'};
            app.VBWEditField.Value = 100;
            app.MeansParmEditFieldLabel = uilabel(app.ControlsPanel);
            app.MeansParmEditFieldLabel.Text = 'Means Parm';
            app.MeansParmEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.MeansParmEditField.Enable = 'off';
            app.MeansParmEditField.Tooltip = {'Main fator for the measure selected'};
            app.PointsEditFieldLabel = uilabel(app.ControlsPanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.PointsEditField.Limits = [1 Inf];
            app.PointsEditField.Editable = 'off';
            app.PointsEditField.Tooltip = {'Depends of the instrument capabilitie'};
            app.PointsEditField.Value = 501;
            app.SmoothEditFieldLabel = uilabel(app.ControlsPanel);
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SmoothEditField.Limits = [0 Inf];
            app.SmoothEditField.Tooltip = {'Smooth factor for the raw data samples'};
            app.SmoothEditField.Value = 0.75;
            app.AttSpinnerLabel = uilabel(app.ControlsPanel);
            app.AttSpinnerLabel.Text = 'Att';
            app.AttSpinner = uispinner(app.ControlsPanel);
            app.AttSpinner.Limits = [0 100];
            app.AttSpinner.Tooltip = {'Attenuation before the mixer. Use saturation test button to a better performance'};
            app.SamplesEditFieldLabel = uilabel(app.ControlsPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SamplesEditField.Limits = [1 10000];
            app.SamplesEditField.Tooltip = {'Samples for single mesure, or moving average for continuous'};
            app.SamplesEditField.Value = 20;
            app.IntervalsEditFieldLabel = uilabel(app.ControlsPanel);
            app.IntervalsEditFieldLabel.Text = 'Interval (s)';
            app.IntervalsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.IntervalsEditField.Limits = [0 Inf];
            app.IntervalsEditField.Tooltip = {'Time between continuous mesures'};
            app.IntervalsEditField.Value = 5;
            app.PreAmpDropDownLabel = uilabel(app.ControlsPanel);
            app.PreAmpDropDownLabel.Text = 'Pre Amp';
            app.PreAmpDropDown = uidropdown(app.ControlsPanel);
            app.PreAmpDropDown.Items = {'Off', 'On'};
            app.PreAmpDropDown.ValueChangedFcn = createCallbackFcn(app, @PreAmpDropDownValueChanged, true);
            app.PreAmpDropDown.Tooltip = {'For high sensibility, it will not considered in Saturation test. Use it carefully'};
            app.PreAmpDropDown.Value = 'Off';
            app.PreAmpLamp = uilamp(app.ControlsPanel);
            app.TotalsDropDownLabel = uilabel(app.ControlsPanel);
            app.TotalsDropDownLabel.Text = 'Totals';
            app.TotalsDropDown = uidropdown(app.ControlsPanel);
            app.TotalsDropDown.Items = {'Sample', 'Accumulate'};
            app.TotalsDropDown.Value = 'Sample';
            app.MethodDropDownLabel = uilabel(app.ControlsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.ControlsPanel);
            app.MethodDropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.MethodDropDown.Value = 'Naive';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Icon = 'LT_blackX.png';
            app.Button.Text = '';
            app.MessageBox = uitextarea(app.LeftPanel);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Panel = uipanel(app.GridLayout2);
            app.Results = uihtml(app.GridLayout2);
            app.Results.HTMLSource = '<html><h4>Results</h4><style>  div {    border: 3px solid blue;    padding-left: 75px;    background-color: yellow;  }  </style>  <p>Select a mesure</p></html>';
            app.MeasuresButtonGroup = uibuttongroup(app.GridLayout2);
            app.MeasuresButtonGroup.Title = 'Measures';
            app.NoneButton = uiradiobutton(app.MeasuresButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.OCWBetaButton = uiradiobutton(app.MeasuresButtonGroup);
            app.OCWBetaButton.Text = 'OCW (Beta %)';
            app.OCWxdBButton = uiradiobutton(app.MeasuresButtonGroup);
            app.OCWxdBButton.Text = 'OCW (xdB)';
            app.ChannelPowerButton = uiradiobutton(app.MeasuresButtonGroup);
            app.ChannelPowerButton.Text = 'Channel Power';
            app.HarmonicsButton = uiradiobutton(app.MeasuresButtonGroup);
            app.HarmonicsButton.Text = 'Harmonics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAPTmain(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end